classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadImageButton                 matlab.ui.control.Button
        StdforgaussianalgorithmEditField  matlab.ui.control.EditField
        StdforgaussianalgorithmEditFieldLabel  matlab.ui.control.Label
        EdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        EdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        ObjectDetectionCanvas           matlab.ui.control.UIAxes
        GreyscaleImageCanvas            matlab.ui.control.UIAxes
        BinaryImage                     matlab.ui.control.UIAxes
        EdgesCanvas                     matlab.ui.control.UIAxes
        BlurImageCanvas                 matlab.ui.control.UIAxes
        OriginalImageCanvas             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image = []; % greyscale image (initially empty);
        GreyscaleImage = []; % greyscale image (initially empty)
        EdgeDetectionAlgorithm = 'Laplace';
        GaussianBlurStd = 0.5;
    end
    
    methods (Access = private)
        
        function processImage(app)
            % Only run when an image is already loaded
            if not(isempty(app.Image))
                % Convert image to grayscale and display
                app.GreyscaleImage = convertToGreyScale(app, app.Image);
                imshow(app.GreyscaleImage, 'parent', app.GreyscaleImageCanvas);
                
                % Calculate and display blurred image
                blurImage = imgaussfilt(app.GreyscaleImage, app.GaussianBlurStd);
                imshow(blurImage, 'parent', app.BlurImageCanvas);
                
                % Find edges and display
                edges = edgeDetection(app, blurImage, app.EdgeDetectionAlgorithm);
                imshow(edges, 'parent', app.EdgesCanvas);
                
                % Find object and display as binary image
                binaryObject = objectDetection(app, edges);
                imshow(binaryObject, 'parent', app.BinaryImage);
                
                % Mask binary image to original image and display
                croppedObject = bsxfun(@times, app.Image, cast(binaryObject, 'like', app.Image));
                imshow(croppedObject, 'parent', app.ObjectDetectionCanvas);
            end
        end
        
        function onChangeImage(app, imagePath)
            % Load and display original image
            app.Image = imread(imagePath);
            imshow(app.Image, 'parent', app.OriginalImageCanvas);
            
            % Process image
            processImage(app);
        end
        
        function onChangeEdgeDetectionAlgorithm(app, algorithm)
            app.EdgeDetectionAlgorithm = algorithm;
            processImage(app);
        end
        
        function onChangeGaussStd(app, std)
            app.GaussianBlurStd = std;
            processImage(app);
        end
        
        function greyscaleImage = convertToGreyScale(~, originalImage)
            [~, ~, channels] = size(originalImage);
            if channels > 1
                greyscaleImage = rgb2gray(originalImage);
            else
                greyscaleImage = originalImage;
            end
        end
        
        function edges = edgeDetection(~, image, algorithm)            
            switch(algorithm)
                case 'Laplace'
                    filter = [0 1 0; 1 -4 1; 0 1 0];
                    edges = uint8(conv2(double(image), double(filter), 'same'));
                    edges = abs(edges);
                    edges = imbinarize(edges, 'adaptive');
                    edges = bwareaopen(edges,50);
                case 'LoG'
                    filter = [0 0 -1 0 0; 0 -1 -2 -1 0; -1 -2 16 -2 -1; 0 -1 -2 -1 0; 0 0 -1 0 0];
                    edges = imfilter(double(image), double(filter));
                    edges = abs(edges);
                    edges = imbinarize(edges, 'adaptive');
                    edges = bwareaopen(edges,200);
                    edges(:,1:10) = 0;
                    edges(1:10,:) = 0;
                case 'Sobel'
                    filterX = [-1 0 1; -2 0 2; -1 0 1];
                    filterY = [1 2 1; 0 0 0; -1 -2 -1];
                    edgesX = abs(conv2(double(image), double(filterX), 'same'));
                    edgesY = abs(conv2(double(image), double(filterY), 'same'));
                    edges = uint8(sqrt(edgesX.^2 + edgesY.^2));
                    edges(:,1) = 0;
                    edges(1,:) = 0;
                    edges = imbinarize(edges, 'adaptive');
                    edges = bwareaopen(edges,200);
                case 'Prewitt'
                    filterX = [-1 0 1; -1 0 1; -1 0 1];
                    filterY = [1 1 1; 0 0 0; -1 -1 -1];
                    edgesX = abs(conv2(double(image), double(filterX), 'same'));
                    edgesY = abs(conv2(double(image), double(filterY), 'same'));
                    edges = uint8(sqrt(edgesX.^2 + edgesY.^2));
                    edges(:,1) = 0;
                    edges(1,:) = 0;
                    
                    edges = imbinarize(edges, 'adaptive');
                    edges = bwareaopen(edges,200);
                case 'Roberts'
                    filterX = [1 0; 0 -1];
                    filterY = [0 1; -1 0]';
                    edgesX = imfilter(image,filterX);
                    edgesY = imfilter(image,filterY);
                    edges = uint8(sqrt(im2double(edgesX.^2 + edgesY.^2)));
                    edges = imbinarize(edges);
                case 'Canny'
                    edges = edge(image, 'canny', [], 'nothinning');
            end
        end
        
        function object = objectDetection(~, edges)
            object = edges;
            [rows, columns, ~] = size(object);
            for i = 1:rows-1
                for j = 1:columns
                    if object(i, j) > 0 && object(i+1, j) == 0
                        k = i+1;
                        while k < rows-1
                            if object(k, j) > 0
                                object(i+1:k-1, j) = 255;
                                break
                            end
                            k = k + 1;
                        end
                    end
                end
            end
            for j = 1:columns-1
                for i = 1:rows
                    if object(i, j) > 0 && object(i, j+1) == 0
                        k = j+1;
                        while k < rows-1
                            if object(i, k) > 0
                                object(i, j+1:k-1) = 1;
                                break
                            end
                            k = k + 1;
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Process default image
            app.onChangeImage('images\banana.jpeg');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.onChangeImage(fname);
            end
        end

        % Value changed function: StdforgaussianalgorithmEditField
        function StdforgaussianalgorithmEditFieldValueChanged(app, event)
            value = app.StdforgaussianalgorithmEditField.Value;
            number = str2double(value);
            
            % Call onChange function if only the input is numeric
            if isnumeric(number)
                app.onChangeGaussStd(number);
            end
        end

        % Value changed function: EdgeDetectionAlgorithmDropDown
        function EdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.EdgeDetectionAlgorithmDropDown.Value;
            app.onChangeEdgeDetectionAlgorithm(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageCanvas = uiaxes(app.UIFigure);
            title(app.OriginalImageCanvas, 'Original Image')
            app.OriginalImageCanvas.XTick = [];
            app.OriginalImageCanvas.YTick = [];
            app.BlurImageCanvas = uiaxes(app.UIFigure);
            title(app.BlurImageCanvas, 'Gaussian Blur')
            app.BlurImageCanvas.XTick = [];
            app.BlurImageCanvas.YTick = [];
            app.EdgesCanvas = uiaxes(app.UIFigure);
            title(app.EdgesCanvas, 'Edge Detection')
            app.EdgesCanvas.XTick = [];
            app.EdgesCanvas.YTick = [];
            app.BinaryImage = uiaxes(app.UIFigure);
            title(app.BinaryImage, 'Binary Image')
            app.BinaryImage.XTick = [];
            app.BinaryImage.YTick = [];
            app.GreyscaleImageCanvas = uiaxes(app.UIFigure);
            title(app.GreyscaleImageCanvas, 'Greyscale Image')
            app.GreyscaleImageCanvas.XTick = [];
            app.GreyscaleImageCanvas.YTick = [];
            app.ObjectDetectionCanvas = uiaxes(app.UIFigure);
            title(app.ObjectDetectionCanvas, 'Object Detection')
            app.ObjectDetectionCanvas.XTick = [];
            app.ObjectDetectionCanvas.YTick = [];
            app.EdgeDetectionAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.EdgeDetectionAlgorithmDropDownLabel.Text = 'Edge Detection Algorithm';
            app.EdgeDetectionAlgorithmDropDown = uidropdown(app.UIFigure);
            app.EdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionAlgorithmDropDownValueChanged, true);
            app.EdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.StdforgaussianalgorithmEditFieldLabel = uilabel(app.UIFigure);
            app.StdforgaussianalgorithmEditFieldLabel.Text = 'Std for gaussian algorithm';
            app.StdforgaussianalgorithmEditField = uieditfield(app.UIFigure, 'text');
            app.StdforgaussianalgorithmEditField.ValueChangedFcn = createCallbackFcn(app, @StdforgaussianalgorithmEditFieldValueChanged, true);
            app.StdforgaussianalgorithmEditField.Value = '0.5';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end